classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label_6                  matlab.ui.control.Label
        ResultLabel              matlab.ui.control.Label
        Label_5                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        DeleteButton             matlab.ui.control.Button
        EnterButton              matlab.ui.control.Button
        ButtonGroup              matlab.ui.container.ButtonGroup
        equationButton           matlab.ui.control.RadioButton
        cosButton                matlab.ui.control.RadioButton
        sinButton                matlab.ui.control.RadioButton
        resultButton             matlab.ui.control.RadioButton
        xyaxisButton             matlab.ui.control.RadioButton
        yaxisButton              matlab.ui.control.RadioButton
        xaxisButton              matlab.ui.control.RadioButton
        Label_3                  matlab.ui.control.Label
        ErrorConsoleLabel_2      matlab.ui.control.Label
        Button2                  matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        yaxisLabel               matlab.ui.control.Label
        EditField_5              matlab.ui.control.EditField
        ListBox_2                matlab.ui.control.ListBox
        EditField_4              matlab.ui.control.EditField
        xaxisLabel               matlab.ui.control.Label
        EditField_3              matlab.ui.control.EditField
        EnterEquationyxLabel     matlab.ui.control.Label
        EqualButton              matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        ArimaticOperationsLabel  matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        SimpleCalculatorLabel    matlab.ui.control.Label
        NoErrorLabel             matlab.ui.control.Label
        ErrorConsoleLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = 0;
            app.EditField_2.Value = 0;
            app.ListBox.Items= {'+','-','*','/'};
            app.ListBox.Value='+';
            app.Label.Text = '+';
            app.EditField_4.Value = '[1,2,3]';
            app.EditField_5.Value = '[4,5,6]';
            app.ListBox_2.Items = {'+','-','*','/'};
            app.ListBox_2.Value = '+';
            app.Label_3.Text = "No Error";


        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            num1= app.EditField.Value;
            num2= app.EditField_2.Value;

            selectedOperation = app.ListBox.Value;

            switch selectedOperation
                case '+'
                    result = num1+num2;
                    app.Label.Text='+';

                case '-'
                    result = num1 - num2;
                    app.Label.Text='-';

                case '*'
                    result = num1 * num2;
                    app.Label.Text='*';
                case '/'
                    if num2 == 0
                        app.NoErrorLabel.Text = "Division by zero";
                        result = 0;
                    else
                        result = num1 / num2;
                        app.Label.Text='/';
                    end
                otherwise
                    result = 0;
                    app.NoErrorLabel.Text = "Unknown operation";

            end
            app.Label_5.Text = sprintf("%.2f",result);
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            try
                num1= str2num(app.EditField_4.Value);
                num2= str2num(app.EditField_5.Value);
    
                selectedOperation = app.ListBox_2.Value;
    
                switch selectedOperation
                    case '+'
                        result = num1+num2;
                        app.Label_2.Text='+';
    
                    case '-'
                        result = num1 - num2;
                        app.Label_2.Text='-';
    
                    case '*'
                        result = num1 .* num2;
                        app.Label_2.Text='*';
                    case '/'
                        if num2 == 0
                            app.Label_3.Text = "Division by zero";
                            result = 0;
                        else
                            result = num1 ./ num2;
                            app.Label_2.Text='/';
                        end
                    otherwise
                        result = 0;
                        app.Label_3.Text = "Unknown operation";
    
                end
                app.Label_6.Text = sprintf("%s",mat2str(result));
                app.Label_2.Text = '';

            catch
                app.Label_3.Text = "Invalid Input or calculation error";
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            selectButton = app.ButtonGroup.SelectedObject;

            switch selectButton.Text
                case 'sin'
                    x = linspace(0,2*pi,100);
                    y = sin(x);
                    plot(app.UIAxes,x,y);
                    xlabel(app.UIAxes,'X');
                    ylabel(app.UIAxes,'Y');

                case 'cos'
                    x = linspace(0,2*pi,100);
                    y = cos(x);
                    plot(app.UIAxes,x,y);
                    xlabel(app.UIAxes,'X');
                    ylabel(app.UIAxes,'Y');

                case 'equation'
                    
                    equ = app.EditField_3.Value;
                    if ~isempty(equ)
                        x = linspace(-10,10,100);
                        y = eval(equ);
                        y = linspace(min(y), max(y), 100);
                        plot(app.UIAxes, x, y);
                       % title(app.UIAxes,'equ');
                        xlabel(app.UIAxes,'X_e');
                        ylabel(app.UIAxes,'Y_e');

                    else
                        cla(app.UIAxes);
                        title(app.UIAxes,'');
                        xlabel(app.UIAxes,'');
                        ylabel(app.UIAxes,'');   

                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ErrorConsoleLabel = uilabel(app.UIFigure);
            app.ErrorConsoleLabel.Text = 'Error Console';
            app.NoErrorLabel = uilabel(app.UIFigure);
            app.NoErrorLabel.Text = 'No Error';
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '!';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ArimaticOperationsLabel = uilabel(app.UIFigure);
            app.ArimaticOperationsLabel.Text = 'Arimatic Operations';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'+', '-', '*', '/'};
            app.ListBox.Value = '+';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.EnterEquationyxLabel = uilabel(app.UIFigure);
            app.EnterEquationyxLabel.Text = 'Enter Equation y(x)';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.xaxisLabel = uilabel(app.UIFigure);
            app.xaxisLabel.Text = 'x-axis';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'+', '-', '*', '/'};
            app.ListBox_2.Value = '+';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.yaxisLabel = uilabel(app.UIFigure);
            app.yaxisLabel.Text = 'y-axis';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '+';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.ErrorConsoleLabel_2 = uilabel(app.UIFigure);
            app.ErrorConsoleLabel_2.Text = 'Error Console';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.xaxisButton = uiradiobutton(app.ButtonGroup);
            app.xaxisButton.Text = 'x-axis';
            app.xaxisButton.Value = true;
            app.yaxisButton = uiradiobutton(app.ButtonGroup);
            app.yaxisButton.Text = 'y-axis';
            app.xyaxisButton = uiradiobutton(app.ButtonGroup);
            app.xyaxisButton.Text = 'xy-axis';
            app.resultButton = uiradiobutton(app.ButtonGroup);
            app.resultButton.Text = 'result';
            app.sinButton = uiradiobutton(app.ButtonGroup);
            app.sinButton.Text = 'sin';
            app.cosButton = uiradiobutton(app.ButtonGroup);
            app.cosButton.Text = 'cos';
            app.equationButton = uiradiobutton(app.ButtonGroup);
            app.equationButton.Text = 'equation';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.Text = 'Delete';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end