classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Label2     matlab.ui.control.Label
        Button     matlab.ui.control.Button
        EditField  matlab.ui.control.NumericEditField
        Label      matlab.ui.control.Label
        UIAxes     matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Text = {'Below 60 ----> Happy :)',' Above 60 ----> Also happy :>)'};
            app.EditField.Value = 0;
            app.Button.Text = "Get image";
            app.Label2.Text = "";
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [fileName, filePath ] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*gif','Image Files (*.png, *.jpg, *jpeg, *.bmp, *.gif' }, "Select an Image");

            if fileName
                fullFilePath = fullfile(filePath,fileName);
                img = imshow(fullFilePath);
                img = double(img);
                app.UIAxes.Color = [1, 1, 1];  
                image(app.UIAxes, img, 'Parent', app.UIAxes);
                axis(app.UIAxes, 'off');

                
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end