classdef traffic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EditField                 matlab.ui.control.NumericEditField
        selectoperationmodeLabel  matlab.ui.control.Label
        TimervalueLabel           matlab.ui.control.Label
        Lamp_3                    matlab.ui.control.Lamp
        Lamp_2                    matlab.ui.control.Lamp
        Lamp                      matlab.ui.control.Lamp
        redButton                 matlab.ui.control.Button
        yellowButton              matlab.ui.control.Button
        greenButton               matlab.ui.control.Button
        autoButton                matlab.ui.control.Button
        manualButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            global a;
            a = arduino();
            clear a;
            a = arduino('COM4', 'Uno');
            global manual;
            manual = 0;
            global auto;
            auto = 0;

        end

        % Button pushed function: manualButton
        function manualButtonPushed(app, event)
            app.autoButton.Enable = "off";
            app.Lamp.Enable = 'off';
            app.Lamp_2.Enable = 'off';
            app.Lamp_3.Enable = 'off';
            global manual;
            manual = 1;
        end

        % Button pushed function: autoButton
        function autoButtonPushed(app, event)
            app.manualButton.Enable = "off";
            app.Lamp.Enable = 'off';
            app.Lamp_2.Enable = 'off';
            app.Lamp_3.Enable = 'off';
            app.yellowButton.Enable= 'off';
            app.greenButton.Enable = 'off';
            app.redButton.Enable = 'off';
            global auto;
            global a;
            auto = 1;
            pause(2);
            while(1)
            app.Lamp.Enable = "on";
            pause(app.EditField.Value);
            writeDigitalPin(a,'D13',1);
            app.Lamp.Enable = "off";
            writeDigitalPin(a,'D13',0);

            app.Lamp_2.Enable = "on";
            pause(app.EditField.Value);
            writeDigitalPin(a,'D12',1);
            app.Lamp_2.Enable = "off";
            writeDigitalPin(a,'D12',0);

            app.Lamp_3.Enable = "on";
            pause(app.EditField.Value);
            writeDigitalPin(a,'D11',1);
            app.Lamp_3.Enable = "off";
            writeDigitalPin(a,'D11',0);
            end
        end

        % Button pushed function: greenButton
        function greenButtonPushed(app, event)
            global a;
            global manual;
            if manual == 1
                writeDigitalPin(a,'D13', 1);
                app.Lamp.Enable = "on";
                pause(app.EditField.Value);
                writeDigitalPin(a,'D13', 0);
                app.Lamp.Enable = "off";
            end

        end

        % Button pushed function: yellowButton
        function yellowButtonPushed(app, event)
            global a;
            global manual;
            if manual == 1
                writeDigitalPin(a,'D12', 1);
                app.Lamp_2.Enable = "on";
                pause(app.EditField.Value);
                writeDigitalPin(a,'D12', 0);
                app.Lamp_2.Enable = "off";
            end
        end

        % Button pushed function: redButton
        function redButtonPushed(app, event)
            global a;
            global manual;
            if manual == 1
                writeDigitalPin(a,'D11', 1);
                app.Lamp_3.Enable = "on";
                pause(app.EditField.Value);
                writeDigitalPin(a,'D11', 0);
                app.Lamp_3.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.manualButton = uibutton(app.UIFigure, 'push');
            app.manualButton.ButtonPushedFcn = createCallbackFcn(app, @manualButtonPushed, true);
            app.manualButton.Text = 'manual';
            app.autoButton = uibutton(app.UIFigure, 'push');
            app.autoButton.ButtonPushedFcn = createCallbackFcn(app, @autoButtonPushed, true);
            app.autoButton.Text = 'auto';
            app.greenButton = uibutton(app.UIFigure, 'push');
            app.greenButton.ButtonPushedFcn = createCallbackFcn(app, @greenButtonPushed, true);
            app.greenButton.Text = 'green';
            app.yellowButton = uibutton(app.UIFigure, 'push');
            app.yellowButton.ButtonPushedFcn = createCallbackFcn(app, @yellowButtonPushed, true);
            app.yellowButton.Text = 'yellow';
            app.redButton = uibutton(app.UIFigure, 'push');
            app.redButton.ButtonPushedFcn = createCallbackFcn(app, @redButtonPushed, true);
            app.redButton.Text = 'red';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.TimervalueLabel = uilabel(app.UIFigure);
            app.TimervalueLabel.Text = 'Timer value';
            app.selectoperationmodeLabel = uilabel(app.UIFigure);
            app.selectoperationmodeLabel.Text = 'select operation mode :';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traffic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end